classdef C3DComputation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        C3DCalculationUIFigure  matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        SettingsTab             matlab.ui.container.Tab
        GridLayout2             matlab.ui.container.GridLayout
        CalculateButton         matlab.ui.control.Button
        LoadButton              matlab.ui.control.Button
        FolderButton            matlab.ui.control.Button
        UITable                 matlab.ui.control.Table
        ResultsTab              matlab.ui.container.Tab
        GridLayout              matlab.ui.container.GridLayout
        SUBJECTDropDown         matlab.ui.control.DropDown
        SUBJECTDropDownLabel    matlab.ui.control.Label
        SaveButton              matlab.ui.control.Button
        FiguresPanel            matlab.ui.container.Panel
        MomentPowerButton       matlab.ui.control.Button
        AngleButton             matlab.ui.control.Button
    end

    properties (Access = public)
        Dir
        File
        Subject
        TableData
        Data
        nRows
        GP
        Files         %Data structure for kinematics & kinetics
        plot_type     %Type of plots: angles, moments, powers
        plotobj
    end
    methods (Access = private)
    
        function updateplot(app)
            % Get Table UI component data
            t = app.UITable.DisplayData;            
            
            % Plot modified data 
            x2 = t.Age;
            y2 = t.BloodPressure(:,2);
            plot(app.UIAxes2,x2,y2,'-o');
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UITable.Enable = 'off';
            app.UITable.Visible = 'off';
            app.Data = [];
            app.Files = []; 
            app.plot_type = 'angle';
        end

        % Callback function
        function UITableDisplayDataChanged(app, event)
            % Update the plots when user sorts the columns of the table
            updateplot(app);
        end

        % Button pushed function: FolderButton
        function FolderButtonPushed(app, event)
            app.Dir = uigetdir(pwd);
            app.C3DCalculationUIFigure.Visible = 'off';
            app.C3DCalculationUIFigure.Visible = 'on';
            app.File = dir(append(app.Dir,'\*.c3d'));
            app.nRows = length(app.File);
            sz = [app.nRows 15];
            varTypes = ["string","string","uint8" ,"uint8","uint8",...
                "uint8","uint8","uint8",...
                "uint8","uint8","uint8",...
                "uint8","uint8","uint8",...
                "string"];
            varNames =  ["Filenames","ID","LKin1","LKin2","LKin3",...
                "RKin1","RKin2","RKin3",...
                "LKinem1","LKinem2","LKinem3",...
                "RKinem1","RKinem2","RKinem3",...
                "AffectedSide"];
            app.UITable.Data = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
            cats = categorical(app.UITable.Data.AffectedSide,{'None','Left','Right'});
            app.UITable.Data.AffectedSide = cats;
            %% Evaluation Start
            for i = 1:app.nRows %Loop over all datafiles
                app.UITable.Data.Filenames(i) = app.File(i).name;
                app.UITable.Data.AffectedSide(i) = 'None';
            end
            app.UITable.Enable = 'on';
            app.UITable.Visible = 'on';

        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged2(app, event)

           app.TableData = app.UITable.Data;
           
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
%             app.CalculateButton.Enable = 'off';
            app.GP = [];
            SubjectID = [];
            Subject_index = 0;
            for i = 1:app.nRows
                app.Data(i).LKin(1) = app.UITable.Data.LKin1(i);
                app.Data(i).LKin(2) = app.UITable.Data.LKin2(i);
                app.Data(i).LKin(3) = app.UITable.Data.LKin3(i);

                app.Data(i).LKinem(1) = app.UITable.Data.LKinem1(i);
                app.Data(i).LKinem(2) = app.UITable.Data.LKinem2(i);
                app.Data(i).LKinem(3) = app.UITable.Data.LKinem3(i);

                app.Data(i).RKin(1) = app.UITable.Data.RKin1(i);
                app.Data(i).RKin(2) = app.UITable.Data.RKin2(i);
                app.Data(i).RKin(3) = app.UITable.Data.RKin3(i);

                app.Data(i).RKinem(1) = app.UITable.Data.RKinem1(i);
                app.Data(i).RKinem(2) = app.UITable.Data.RKinem2(i);
                app.Data(i).RKinem(3) = app.UITable.Data.RKinem3(i);
                
                ID = string(app.UITable.Data.ID(i));
                if isempty(SubjectID) || (SubjectID(length(SubjectID)) ~= ID)
                    SubjectID = [SubjectID ID];
                    Subject_index = Subject_index + 1;
                    app.Subject(Subject_index).name = ID;
                    Init_Subjectdata(app,Subject_index);
                end
                app.Data(i).AngleFit = [];      
                app.Data(i).PowerFit = [];      
                app.Data(i).MomentFit = [];     
                app.Data(i).WorkFit = []; 
                app.Data(i).GP = [];

                fid = fopen(char(app.TableData.Filenames(i)));
                if (fid == -1)
                    continue
                end
                file = char(app.TableData.Filenames(i));  
                app.Data(i).name = erase(file,'.c3d');
                %% Read in Header Data
                [POINTdat,VideoFrameRate,ANALOGdat,AnalogFrameRate,ParameterGroup,HeaderGroup] = readC3D_MoveAbilityLab(file);
                if isempty(POINTdat)
                    continue
                end
                %% Read in Point Data (Angles,Moment,Power,Force)
                [app.Files] = KinematicDataInread(ParameterGroup,POINTdat,app.Files,i,app.TableData.Filenames);
                if isempty(app.Files(i).Data)   %If no information increment file number
                    continue
                end
                %% Read in Events & Cycles
                [events,numevents] = EventDefinition(ParameterGroup,VideoFrameRate);
                [app.Data(i).LStart,app.Data(i).LEnd,app.Data(i).nLcyc,app.Data(i).RStart,app.Data(i).REnd,app.Data(i).nRcyc] = CycleDefinition(numevents,events,HeaderGroup);
                if (app.Data(i).nRcyc == 0 && app.Data(i).nLcyc == 0)  %If no cycles increment file number
                    continue
                end
                %% Spline Fit Kinematics & Kinetics
                %...parameter(1,:) = sagital plane,...parameter(2,:) =
                %frontal plane,...parameter(3,:) = transverse plane
                [app.Data(i).AngleFit,app.Data(i).MomentFit,app.Data(i).PowerFit,app.Data(i).WorkFit] = SplineFit(app,app.Files,app.Data(i).AngleFit,app.Data(i).MomentFit,app.Data(i).PowerFit,app.Data(i).WorkFit,i,app.Data(i).LStart,app.Data(i).LEnd,app.Data(i).nLcyc,app.Data(i).RStart,app.Data(i).REnd,app.Data(i).nRcyc);
%                 %% Separation into Affected and Unaffected Side for Asymmetric Gait
%                 AffectedSide = table2cell(app.TableData(i,'AffectedSide'));
%                 AffectedSide = string(AffectedSide);
%                 [app.Data(i).ASAngleFit,app.Data(i).ASMomentFit,app.Data(i).ASPowerFit,app.Data(i).ASPosWorkFit,app.Data(i).ASNegWorkFit,app.Data(i).ASgait] = ASGait(app.Data(i).AngleFit,app.Data(i).MomentFit,app.Data(i).PowerFit,app.Data(i).PosWorkFit,app.Data(i).NegWorkFit,AffectedSide,i);
                %% Gait Parameter
                [app.GP] = GPComputation(app,app.Data(i).AngleFit,app.Data(i).MomentFit,app.Data(i).PowerFit,app.GP,app.TableData.Filenames,i);
                %% Grouping angle data coressponding to each subject
                group_subject_angle(app,Subject_index,i);
                %% Grouping moment data coressponding to each subject
                group_subject_moment(app,Subject_index,i);
                %% Grouping power data coressponding to each subject
                group_subject_power(app,Subject_index,i)
                %% Grouping Work data coressponding to each subject
                group_subject_Work(app,Subject_index,i)
            end

            
            % operations on the new structure over each subject
            meanL =[];
            sdL = [];
            meanR = [];
            sdR = [];
            for i = 1:length(app.Subject)
                % Create summary profile
                getSummary(app,i);
                %iSCI_GPcomputation(app,i)
%                 app.Subject(i).Angle.Sagittal.Thorax.Summary.Left_mean = mean(app.Subject(i).Angle.Sagittal.Thorax.left,2);
            % Save Control Group Dataset
                if strncmp(app.Subject(i).name, 'Control',6)
                    % GDI calculation
                    GDI_control_calculation(app, i);
                    %GPS Calculation
                    GPS_control_calculation(app, i);
                    
                    %save the final version of CG_Dataset
                    CG_Dataset = app.Subject(i);
                    save('Results/CG_Dataset.mat','CG_Dataset');
                    
                elseif strncmp(app.Subject(i).name, 'Control',6)==0
                    load('Results/CG_Dataset.mat');
                    app.AngleButton.Enable = 'off';
                    app.MomentPowerButton.Enable = 'on';
                        %Plots
                    %Angels Plot
                    iSCI_AnglePlot(app,i,CG_Dataset);
                    title = sprintf('Results/Plots/AnglePlot_%s.pdf', app.Subject(i).name);
                    saveas(gcf,title);
                    %Angels Area Plot
                    iSCI_AngleAreaPlot(app,i,CG_Dataset)
                    title = sprintf('Results/Plots/AngleAreaPlot_%s.pdf', app.Subject(i).name);
                    saveas(gcf,title);
                    %Moments & Power Plot
                    iSCI_MomentPowerPlot(app,i,CG_Dataset)
                    title = sprintf('Results/Plots/MomentPowerPlot_%s.pdf', app.Subject(i).name);
                    saveas(gcf,title);
                    %Moments & Power Area Plot
                    iSCI_MomentPowerAreaPlot(app,i,CG_Dataset)
                    title = sprintf('Results/Plots/MomentPowerAreaPlot_%s.pdf', app.Subject(i).name);
                    saveas(gcf,title);
                    
                        % GDI calculation
                    GDI_computation(app, i, CG_Dataset);
                    meanL = [meanL; app.Subject(i).GDI.meanL];
                    sdL = [sdL;app.Subject(i).GDI.sdL];
                    meanR = [meanR; app.Subject(i).GDI.meanR];
                    sdR = [sdR;app.Subject(i).GDI.sdR];
                    
                        % GPS calculation
                    GPS_calculation(app,i,CG_Dataset)
                    title = sprintf('Results/Plots/MAP_%s.pdf', app.Subject(i).name);
                    saveas(gcf,title);

                    
                end
            end
            %Save GDI
            subID = app.Subject(:).name;
            GDI_Table = table (subID,meanL,sdL,meanR,sdR);
            filename = 'results/GDI_subjects.xlsx';
            writetable(GDI_Table,filename,'Sheet',1)
            
            %Save GP
            GPXLSX = 'GaitParameter.xlsx'; %Filename for Gait Parameter
            writetable(struct2table(app.GP),GPXLSX)

            FileInputs = 'ReadIn.xlsx';
            writetable(app.UITable.Data,FileInputs)
            app.CalculateButton.Enable = 'on';
        end

        % Button down function: FiguresPanel
        function FiguresPanelButtonDown(app, event)
            coordinates = get(app,'CurrentPoint');
            
        end

        % Button pushed function: MomentPowerButton
        function MomentPowerButtonPushed(app, event)
            app.MomentPowerButton.Enable = 'off';
            app.AngleButton.Enable = 'on';
            plot_moment(app);
        end

        % Button pushed function: AngleButton
        function AngleButtonPushed(app, event)
            app.MomentPowerButton.Enable = 'on';
            app.AngleButton.Enable = 'off';
            plot_angle(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            [filename, pathname] = uiputfile('*.png', 'Save the file as');
            if isnumeric(filename)
               disp('User pushed cancel. Not saving anything')
            else
               exportgraphics(app.plotobj,fullfile(pathname, filename));
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [filename, pathname] = uigetfile('.xlsx');
            data = readtable(fullfile(pathname, filename));
            app.C3DCalculationUIFigure.Visible = 'off';
            app.C3DCalculationUIFigure.Visible = 'on';
            app.UITable.Data = data;
            app.TableData = data;
            app.nRows = height(data);
            ID = 'a';
            Subjectlist = [];
            for i = 1:app.nRows
                if ID ~= string(data.ID(i))
                    ID = string(data.ID(i));
                    Subjectlist = [Subjectlist ID];
                end
            end
            app.SUBJECTDropDown.Items = Subjectlist;
            app.UITable.Enable = 'on';
            app.UITable.Visible = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.C3DCalculationUIFigure = uifigure('Visible', 'off');
            app.C3DCalculationUIFigure.Name = 'C3D Calculation';
            app.TabGroup = uitabgroup(app.C3DCalculationUIFigure);
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.GridLayout2 = uigridlayout(app.SettingsTab);
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = {'Data Files'; 'ID'; 'LKin1'; 'LKin2'; 'LKin3'; 'RKin1'; 'RKin2'; 'RKin3'; 'LKinem1'; 'LKinem2'; 'LKinem3'; 'RKinem1'; 'RKinem2'; 'RKinem3'; 'Affected Side'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true true true true true true true true true true true true true];
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged2, true);
            app.FolderButton = uibutton(app.GridLayout2, 'push');
            app.FolderButton.ButtonPushedFcn = createCallbackFcn(app, @FolderButtonPushed, true);
            app.FolderButton.Text = 'Folder...';
            app.LoadButton = uibutton(app.GridLayout2, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.CalculateButton = uibutton(app.GridLayout2, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.GridLayout = uigridlayout(app.ResultsTab);
            app.AngleButton = uibutton(app.GridLayout, 'push');
            app.AngleButton.ButtonPushedFcn = createCallbackFcn(app, @AngleButtonPushed, true);
            app.AngleButton.Text = 'Angle';
            app.MomentPowerButton = uibutton(app.GridLayout, 'push');
            app.MomentPowerButton.ButtonPushedFcn = createCallbackFcn(app, @MomentPowerButtonPushed, true);
            app.MomentPowerButton.Text = 'Moment/Power';
            app.FiguresPanel = uipanel(app.GridLayout);
            app.FiguresPanel.Title = 'Figures';
            app.FiguresPanel.ButtonDownFcn = createCallbackFcn(app, @FiguresPanelButtonDown, true);
            app.SaveButton = uibutton(app.GridLayout, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SUBJECTDropDownLabel = uilabel(app.GridLayout);
            app.SUBJECTDropDownLabel.Text = 'SUBJECT';
            app.SUBJECTDropDown = uidropdown(app.GridLayout);
            % Show the figure after all components are created
            app.C3DCalculationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = C3DComputation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.C3DCalculationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.C3DCalculationUIFigure)
        end
    end
end